classdef Proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ELNButton             matlab.ui.control.Button
        PDETButton            matlab.ui.control.Button
        MINERIAButton         matlab.ui.control.Button
        PNISButton            matlab.ui.control.Button
        Homicidios2017Button  matlab.ui.control.Button
        Homicidios2018Button  matlab.ui.control.Button
        CULTIVOSButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ELNButton
        function ELNButtonPushed(app, event)
            dataELN=readtable('Datos ELN.xlsx');
            figELN=figure('Name','Gráfica Datos ELN ','NumberTitle', 'off');
geoscatter(dataELN.LATITUDMUNICIPIO,dataELN.LONGITUDMUNICIPIO,'*');
        end

        % Button pushed function: PDETButton
        function PDETButtonPushed(app, event)
            dataPDET=readtable('Datos PDET.xlsx');
            figPDET=figure('Name', 'Gráfica Datos PDET','NumberTitle', 'off');
            geoscatter(dataPDET.LATITUDMUNICIPIO,dataPDET.LONGITUDMUNICIPIO,'*');
            text(0,0,'Datos');
        end

        % Button pushed function: MINERIAButton
        function MINERIAButtonPushed(app, event)
            dataMineria=readtable('Datos Minería Ilegal Oro.xlsx');
            figMineria=figure('Name', 'Gráfica Datos Minería','NumberTitle', 'off');
            geoscatter(dataMineria.LATITUDMUNICIPIO,dataMineria.LONGITUDMUNICIPIO,'*');

        end

        % Button pushed function: PNISButton
        function PNISButtonPushed(app, event)
            dataPNIS=readtable('Datos PNIS.xlsx');
            figPNIS=figure('Name', 'Gráfica Datos PNIS','NumberTitle', 'off');
            dataPNIS.NOMBREDEPARTAMENTO=categorical(dataPNIS.NOMBREDEPARTAMENTO);
            geobubble(dataPNIS,'LATITUDMUNICIPIO','LONGITUDMUNICIPIO','SizeVariable','TOTALGENERAL','ColorVariable','NOMBREDEPARTAMENTO');
        end

        % Button pushed function: Homicidios2017Button
        function Homicidios2017ButtonPushed(app, event)
            dataFARC=readtable('Datos Disidencias FARC.xlsx');
            figHomicidios2017=figure('Name', 'Gráfica Datos Homicidios 2017','NumberTitle', 'off');
            dataFARC.NOMBREDEPARTAMENTO=categorical(dataFARC.NOMBREDEPARTAMENTO);
            geobubble(dataFARC,'LATITUDMUNICIPIO','LONGITUDMUNICIPIO','SizeVariable','x_HOMICIDIOS2017','ColorVariable','NOMBREDEPARTAMENTO');
        end

        % Button pushed function: Homicidios2018Button
        function Homicidios2018ButtonPushed(app, event)
            dataFARC=readtable('Datos Disidencias FARC.xlsx');
            figHomicidios2018=figure('Name', 'Gráfica Datos Homicidios 2018','NumberTitle', 'off');
            dataFARC.NOMBREDEPARTAMENTO=categorical(dataFARC.NOMBREDEPARTAMENTO);
            geobubble(dataFARC,'LATITUDMUNICIPIO','LONGITUDMUNICIPIO','SizeVariable','x_HOMICIDIOS2018','ColorVariable','NOMBREDEPARTAMENTO');
        end

        % Button pushed function: CULTIVOSButton
        function CULTIVOSButtonPushed(app, event)
            dataCultivos=readtable('Datos Cultivos Coca 2019.xlsx');
            figCultivos=figure('Name', 'Gráfica Datos Cultivos Coca','NumberTitle', 'off');
            dataCultivos.NOMBREDEPARTAMENTO=categorical(dataCultivos.NOMBREDEPARTAMENTO);
            geobubble(dataCultivos,'LATITUDMUNICIPIO','LONGITUDMUNICIPIO','SizeVariable','HECT_REASDECOCA','ColorVariable','NOMBREDEPARTAMENTO');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ELNButton = uibutton(app.UIFigure, 'push');
            app.ELNButton.ButtonPushedFcn = createCallbackFcn(app, @ELNButtonPushed, true);
            app.ELNButton.Text = 'ELN';
            app.PDETButton = uibutton(app.UIFigure, 'push');
            app.PDETButton.ButtonPushedFcn = createCallbackFcn(app, @PDETButtonPushed, true);
            app.PDETButton.Text = {'PDET'; ''};
            app.MINERIAButton = uibutton(app.UIFigure, 'push');
            app.MINERIAButton.ButtonPushedFcn = createCallbackFcn(app, @MINERIAButtonPushed, true);
            app.MINERIAButton.Text = {'MINERIA'; ''};
            app.PNISButton = uibutton(app.UIFigure, 'push');
            app.PNISButton.ButtonPushedFcn = createCallbackFcn(app, @PNISButtonPushed, true);
            app.PNISButton.Text = {'PNIS'; ''};
            app.Homicidios2017Button = uibutton(app.UIFigure, 'push');
            app.Homicidios2017Button.ButtonPushedFcn = createCallbackFcn(app, @Homicidios2017ButtonPushed, true);
            app.Homicidios2017Button.Text = {'Homicidios 2017'; ''};
            app.Homicidios2018Button = uibutton(app.UIFigure, 'push');
            app.Homicidios2018Button.ButtonPushedFcn = createCallbackFcn(app, @Homicidios2018ButtonPushed, true);
            app.Homicidios2018Button.Text = {'Homicidios 2018'; ''};
            app.CULTIVOSButton = uibutton(app.UIFigure, 'push');
            app.CULTIVOSButton.ButtonPushedFcn = createCallbackFcn(app, @CULTIVOSButtonPushed, true);
            app.CULTIVOSButton.Text = {'CULTIVOS'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end